classdef Port < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout          matlab.ui.container.GridLayout
        portlinevalvePanel  matlab.ui.container.Panel
        GridLayout2         matlab.ui.container.GridLayout
        EditField_Line8     matlab.ui.control.EditField
        Label_8             matlab.ui.control.Label
        EditField_Line7     matlab.ui.control.EditField
        Label_7             matlab.ui.control.Label
        EditField_Line6     matlab.ui.control.EditField
        Label_6             matlab.ui.control.Label
        EditField_Line5     matlab.ui.control.EditField
        Label_5             matlab.ui.control.Label
        EditField_Line4     matlab.ui.control.EditField
        Label_4             matlab.ui.control.Label
        EditField_Line3     matlab.ui.control.EditField
        Label_3             matlab.ui.control.Label
        EditField_Line2     matlab.ui.control.EditField
        Label_2             matlab.ui.control.Label
        EditField_Line1     matlab.ui.control.EditField
        Label               matlab.ui.control.Label
    end

    properties (AbortSet, Access = public, GetObservable, SetObservable)
        enable (1,1) logical = false;
        port (1,1) double {mustBeNonnegative(port), mustBeInteger(port)} = 0; % port on device
        vlmap (8,1) double {mustBeNonnegative(vlmap), mustBeInteger(vlmap)} = 1:8; % valve-line mapping vector
    end
    
    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            
        end

        % Value changed function: EditField_Line1
        function EditField_Line1ValueChanged(comp, event)
            value = comp.EditField_Line1.Value;
            comp.vlmap(1) = str2double(value);
        end

        % Value changed function: EditField_Line2
        function EditField_Line2ValueChanged(comp, event)
            value = comp.EditField_Line2.Value;
            comp.vlmap(2) = str2double(value);
        end

        % Value changed function: EditField_Line3
        function EditField_Line3ValueChanged(comp, event)
            value = comp.EditField_Line3.Value;
            comp.vlmap(3) = str2double(value);
        end

        % Value changed function: EditField_Line4
        function EditField_Line4ValueChanged(comp, event)
            value = comp.EditField_Line4.Value;
            comp.vlmap(4) = str2double(value);
        end

        % Value changed function: EditField_Line5
        function EditField_Line5ValueChanged(comp, event)
            value = comp.EditField_Line5.Value;
            comp.vlmap(5) = str2double(value);
        end

        % Value changed function: EditField_Line6
        function EditField_Line6ValueChanged(comp, event)
            value = comp.EditField_Line6.Value;
            comp.vlmap(6) = str2double(value);
        end

        % Value changed function: EditField_Line7
        function EditField_Line7ValueChanged(comp, event)
            value = comp.EditField_Line7.Value;
            comp.vlmap(7) = str2double(value);
        end

        % Value changed function: EditField_Line8
        function EditField_Line8ValueChanged(comp, event)
            value = comp.EditField_Line8.Value;
            comp.vlmap(8) = str2double(value);
        end
    end

    methods (Access = protected)
        
        % 公共属性值发生更改时执行的代码
        function update(comp)
            % update value
            for k = 1:8
                % set status
                evstr = sprintf("comp.EditField_Line%d.Enable " + ...
                    "= comp.enable;", k);
                eval(evstr);

                % set the value
                if comp.vlmap(k) ~= 0
                    evstr = sprintf("comp.EditField_Line%d.Value = " + ...
                        "string(comp.vlmap(%d));", k, k);
                else
                    evstr = sprintf("comp.EditField_Line%d.Value = """";", k);
                end
                eval(evstr);
            end

            % update label
            comp.Label.Text = sprintf("%d.0", comp.port);
            for k = 2:8
                evstr = sprintf("comp.Label_%d.Text = ""%d.%d"";", k, comp.port,k-1);
                eval(evstr);
            end
        end

        % 创建底层组件
        function setup(comp)

            comp.Position = [1 1 100 290];
            comp.BackgroundColor = [0.94 0.94 0.94];

            % Create GridLayout
            comp.GridLayout = uigridlayout(comp);
            comp.GridLayout.ColumnWidth = {'1x'};
            comp.GridLayout.RowHeight = {'1x'};
            comp.GridLayout.Padding = [0 0 0 0];

            % Create portlinevalvePanel
            comp.portlinevalvePanel = uipanel(comp.GridLayout);
            comp.portlinevalvePanel.TitlePosition = 'centertop';
            comp.portlinevalvePanel.Title = 'port.line > valve';
            comp.portlinevalvePanel.Layout.Row = 1;
            comp.portlinevalvePanel.Layout.Column = 1;
            comp.portlinevalvePanel.FontWeight = 'bold';

            % Create GridLayout2
            comp.GridLayout2 = uigridlayout(comp.portlinevalvePanel);
            comp.GridLayout2.ColumnWidth = {30, '1x'};
            comp.GridLayout2.RowHeight = {22, 22, 22, 22, 22, 22, 22, 22};

            % Create Label
            comp.Label = uilabel(comp.GridLayout2);
            comp.Label.HorizontalAlignment = 'right';
            comp.Label.FontSize = 14;
            comp.Label.FontWeight = 'bold';
            comp.Label.Layout.Row = 1;
            comp.Label.Layout.Column = 1;
            comp.Label.Text = '0.0';

            % Create EditField_Line1
            comp.EditField_Line1 = uieditfield(comp.GridLayout2, 'text');
            comp.EditField_Line1.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @EditField_Line1ValueChanged, true);
            comp.EditField_Line1.Tag = '0';
            comp.EditField_Line1.HorizontalAlignment = 'center';
            comp.EditField_Line1.FontSize = 14;
            comp.EditField_Line1.FontWeight = 'bold';
            comp.EditField_Line1.Layout.Row = 1;
            comp.EditField_Line1.Layout.Column = 2;

            % Create Label_2
            comp.Label_2 = uilabel(comp.GridLayout2);
            comp.Label_2.HorizontalAlignment = 'right';
            comp.Label_2.FontSize = 14;
            comp.Label_2.FontWeight = 'bold';
            comp.Label_2.Layout.Row = 2;
            comp.Label_2.Layout.Column = 1;
            comp.Label_2.Text = '0.1';

            % Create EditField_Line2
            comp.EditField_Line2 = uieditfield(comp.GridLayout2, 'text');
            comp.EditField_Line2.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @EditField_Line2ValueChanged, true);
            comp.EditField_Line2.Tag = '1';
            comp.EditField_Line2.HorizontalAlignment = 'center';
            comp.EditField_Line2.FontSize = 14;
            comp.EditField_Line2.FontWeight = 'bold';
            comp.EditField_Line2.Layout.Row = 2;
            comp.EditField_Line2.Layout.Column = 2;

            % Create Label_3
            comp.Label_3 = uilabel(comp.GridLayout2);
            comp.Label_3.HorizontalAlignment = 'right';
            comp.Label_3.FontSize = 14;
            comp.Label_3.FontWeight = 'bold';
            comp.Label_3.Layout.Row = 3;
            comp.Label_3.Layout.Column = 1;
            comp.Label_3.Text = '0.2';

            % Create EditField_Line3
            comp.EditField_Line3 = uieditfield(comp.GridLayout2, 'text');
            comp.EditField_Line3.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @EditField_Line3ValueChanged, true);
            comp.EditField_Line3.Tag = '2';
            comp.EditField_Line3.HorizontalAlignment = 'center';
            comp.EditField_Line3.FontSize = 14;
            comp.EditField_Line3.FontWeight = 'bold';
            comp.EditField_Line3.Layout.Row = 3;
            comp.EditField_Line3.Layout.Column = 2;

            % Create Label_4
            comp.Label_4 = uilabel(comp.GridLayout2);
            comp.Label_4.HorizontalAlignment = 'right';
            comp.Label_4.FontSize = 14;
            comp.Label_4.FontWeight = 'bold';
            comp.Label_4.Layout.Row = 4;
            comp.Label_4.Layout.Column = 1;
            comp.Label_4.Text = '0.3';

            % Create EditField_Line4
            comp.EditField_Line4 = uieditfield(comp.GridLayout2, 'text');
            comp.EditField_Line4.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @EditField_Line4ValueChanged, true);
            comp.EditField_Line4.Tag = '3';
            comp.EditField_Line4.HorizontalAlignment = 'center';
            comp.EditField_Line4.FontSize = 14;
            comp.EditField_Line4.FontWeight = 'bold';
            comp.EditField_Line4.Layout.Row = 4;
            comp.EditField_Line4.Layout.Column = 2;

            % Create Label_5
            comp.Label_5 = uilabel(comp.GridLayout2);
            comp.Label_5.HorizontalAlignment = 'right';
            comp.Label_5.FontSize = 14;
            comp.Label_5.FontWeight = 'bold';
            comp.Label_5.Layout.Row = 5;
            comp.Label_5.Layout.Column = 1;
            comp.Label_5.Text = '0.4';

            % Create EditField_Line5
            comp.EditField_Line5 = uieditfield(comp.GridLayout2, 'text');
            comp.EditField_Line5.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @EditField_Line5ValueChanged, true);
            comp.EditField_Line5.Tag = '4';
            comp.EditField_Line5.HorizontalAlignment = 'center';
            comp.EditField_Line5.FontSize = 14;
            comp.EditField_Line5.FontWeight = 'bold';
            comp.EditField_Line5.Layout.Row = 5;
            comp.EditField_Line5.Layout.Column = 2;

            % Create Label_6
            comp.Label_6 = uilabel(comp.GridLayout2);
            comp.Label_6.HorizontalAlignment = 'right';
            comp.Label_6.FontSize = 14;
            comp.Label_6.FontWeight = 'bold';
            comp.Label_6.Layout.Row = 6;
            comp.Label_6.Layout.Column = 1;
            comp.Label_6.Text = '0.5';

            % Create EditField_Line6
            comp.EditField_Line6 = uieditfield(comp.GridLayout2, 'text');
            comp.EditField_Line6.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @EditField_Line6ValueChanged, true);
            comp.EditField_Line6.Tag = '5';
            comp.EditField_Line6.HorizontalAlignment = 'center';
            comp.EditField_Line6.FontSize = 14;
            comp.EditField_Line6.FontWeight = 'bold';
            comp.EditField_Line6.Layout.Row = 6;
            comp.EditField_Line6.Layout.Column = 2;

            % Create Label_7
            comp.Label_7 = uilabel(comp.GridLayout2);
            comp.Label_7.HorizontalAlignment = 'right';
            comp.Label_7.FontSize = 14;
            comp.Label_7.FontWeight = 'bold';
            comp.Label_7.Layout.Row = 7;
            comp.Label_7.Layout.Column = 1;
            comp.Label_7.Text = '0.6';

            % Create EditField_Line7
            comp.EditField_Line7 = uieditfield(comp.GridLayout2, 'text');
            comp.EditField_Line7.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @EditField_Line7ValueChanged, true);
            comp.EditField_Line7.Tag = '6';
            comp.EditField_Line7.HorizontalAlignment = 'center';
            comp.EditField_Line7.FontSize = 14;
            comp.EditField_Line7.FontWeight = 'bold';
            comp.EditField_Line7.Layout.Row = 7;
            comp.EditField_Line7.Layout.Column = 2;

            % Create Label_8
            comp.Label_8 = uilabel(comp.GridLayout2);
            comp.Label_8.HorizontalAlignment = 'right';
            comp.Label_8.FontSize = 14;
            comp.Label_8.FontWeight = 'bold';
            comp.Label_8.Layout.Row = 8;
            comp.Label_8.Layout.Column = 1;
            comp.Label_8.Text = '0.7';

            % Create EditField_Line8
            comp.EditField_Line8 = uieditfield(comp.GridLayout2, 'text');
            comp.EditField_Line8.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @EditField_Line8ValueChanged, true);
            comp.EditField_Line8.Tag = '7';
            comp.EditField_Line8.HorizontalAlignment = 'center';
            comp.EditField_Line8.FontSize = 14;
            comp.EditField_Line8.FontWeight = 'bold';
            comp.EditField_Line8.Layout.Row = 8;
            comp.EditField_Line8.Layout.Column = 2;
            
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end