classdef EBVideoPlayer < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout            matlab.ui.container.GridLayout
        Slider_Time           matlab.ui.control.Slider
        GridLayout2           matlab.ui.container.GridLayout
        Button_Play           matlab.ui.control.Button
        GridLayout4           matlab.ui.container.GridLayout
        Button_PreviousFrame  matlab.ui.control.Button
        Button_SlowDown       matlab.ui.control.Button
        Button_Setting        matlab.ui.control.Button
        GridLayout3           matlab.ui.container.GridLayout
        Button_NextFrame      matlab.ui.control.Button
        Button_CurrentFrame   matlab.ui.control.Button
        Button_SpeedUp        matlab.ui.control.Button
        UIAxes_Viewer         matlab.ui.control.UIAxes
    end

    properties (AbortSet, Access = private, GetObservable, SetObservable)
        withDetectBox (1,1) logical = true;
        withMetadata (1,1) logical = true;
        withPlaySpeed (1,1) logical = true;
        withScaleBar (1,1) logical = false;
        withTimeStamp (1,1) logical = true;
        withTraces (1,1) logical = false;
    end
    
    properties (AbortSet, Access = public, GetObservable, SetObservable)
        Connect (1,1) logical = false;
        Enable (1,1) logical = false;
    end
    

    properties (Constant, Hidden)
        PLAYER_MIN_DURATION = 0.125     % minimal player running duration
        ACQUIRE_DURATION = 0.4          % acquirer running duration
        WAIT_KERNEL_DELAY = 0.1       
    end

    properties (Access = public)
        ScaleBarLength = 1              % scale bar length in world coordinate system, unit as cm
    end

    properties (GetAccess = public, Dependent)
        WithMarkers
    end

    properties (Access = private)
        kernel      (1,1)               % EBKernel object
        pConfig     (1,1)               % PlayerConfiguration object
        player      (1,1)   timer       % timer for video frame display
        acquirer    (1,1)   timer       % timer for data acqusition from kernel
        opts        (1,1)   struct      % display options with fields ["Image", "Marker"]
        hImage      (1,1)               % handle of image, matlab.graphics.primitive.Image
        hTime       (1,1)               % handle of time text, matlab.graphics.primitive.Text
        hScale      (1,1)   struct      % struct with [scale line handle, scale text handle]
        hSpeed      (1,1)               % handle of play speed text, matlab.graphics.primitive.Text
        hMeta       (1,1)               % handle of meta data text, matlab.graphics.primitive.Text
        hBoxes      (1,1)               % 1-by-1 patch object with some polygons
        hTrace      (1,1)               % store trajectory, <string> |-> <cell>, id -> {line, color}
        video       (1,1)               % 1-by-1 EBVideo object, store required video frames
        screen_size (1,2)   double      % real screen size
        speed_ratio (1,1)   double      % duration ratio, default as 2, in [1, 2, 4, 8]
        frame_ptr   (1,1)   double      % current frame pointer, 1,2,...,n
        mpath       (1,1)   string      % EBVideoPlayer current folder
    end

    methods
        function r = get.WithMarkers(comp)
            mk = EBVideoMarker(comp.withDetectBox, ...
                               comp.withMetadata, ...
                               comp.withPlaySpeed, ...
                               comp.withScaleBar, ...
                               comp.withTimeStamp, ...
                               comp.withTraces);
            r = mk.Markers;
        end

        function delete(comp)
            stop(comp.acquirer);
            stop(comp.player);
            delete(comp.player);
            delete(comp.acquirer);
            
            comp.video.delete();        % delete handle

            if isobject(comp.pConfig) && isvalid(comp.pConfig)
                comp.pConfig.delete();
            end

            warning("on", 'EBVideo:emptyVideo');
        end
    end

    methods (Access = ?PlayerConfiguration)

        function SetTimeStampState(comp, state)
            arguments
                comp
                state   (1,1)   logical 
            end
            comp.withTimeStamp = state;
        end

        function SetScaleBarState(comp, state)
            arguments
                comp
                state   (1,1)   logical
            end
            comp.withScaleBar = state;
        end

        function SetPlayerSpeedState(comp, state)
            arguments
                comp
                state   (1,1)   logical
            end
            comp.withPlaySpeed = state;
        end

        function SetMetadataState(comp, state)
            arguments
                comp
                state   (1,1)   logical
            end
            comp.withMetadata = state;
        end

        function SetDetectBoxState(comp, state)
            arguments
                comp
                state   (1,1)   logical
            end
            comp.withDetectBox = state;
        end

        function SetTracesState(comp, state)
            arguments
                comp
                state   (1,1)   logical
            end
            comp.withTraces = state;
        end
    end

    % run only once
    methods (Access = ?EasyBehaviour)
        function SetKernel(comp, ker)
            arguments
                comp
                ker     (1,1)   EBKernel
            end

            % copy kernel handle
            comp.kernel = ker;
        end

        function UpdateViewer(comp)
            % update screen size
            UpdateScreenSize(comp);

            % update add-ons location
            UpdateAddonsLocation(comp);

            drawnow
        end

        function SetDisplayOptions(comp, opts)
            arguments
                comp
                opts    (1,1)   struct
            end

            comp.opts = opts;
        end
    end

    methods (Access = private)
        %% Acquirer Timer calling
        function AcquirerTasks(comp, src, evt)
            src; %#ok<VUNUS>
            evt; %#ok<VUNUS>

            try
                % try to acquire one frame from kernel
                status = AcquireFromKernel(comp);

                if true == status
                    UpdateProgressBarLimits(comp);
                end
            catch   % kernel may be not ready, skip exception
            end
        end

        function status = AcquireFromKernel(comp)
            if isobject(comp.kernel) ...
                    && isvalid(comp.kernel)

                %% Acquire Data
                % get current video frame
                vf = comp.kernel.CurrentVideoFrame; % EBVideoFrame object

                if isempty(vf)
                    % kernel unready, return
                    status = false;
                else
                    if comp.kernel.IsRunning
                        % kernel running mode (online)
                        % get current display frame and compare
                        cvf = comp.video.GetLastFrame();

                        if isequal(vf, cvf)
                            % require faster than kernel generation, drop
                            % this frame
                            status = false;
                        else
                            % kernel send a new frame
                            % push into video queue
                            comp.video.AddFrame(vf);
                            status = true;
                        end
                    else
                        status = false;
                    end
                end
            else
                status = false;
            end
        end

        function UpdateProgressBarLimits(comp)
            nf = numel(comp.video);
            if nf > 1
                comp.Slider_Time.Limits = [1, nf];
                comp.Slider_Time.MajorTicks = 20:20:nf;
                comp.Slider_Time.MajorTickLabels = string(20:20:nf);
                comp.Slider_Time.MinorTicks = 1:nf;
            end
        end

        function StartAcquiring(comp, src, evt)
            src; %#ok<VUNUS>
            evt; %#ok<VUNUS>

            % trigger player
            start(comp.player);

            comp.Slider_Time.Enable = "on";
        end

        function StopAcquiring(comp, src, evt)
            src; %#ok<VUNUS>
            evt; %#ok<VUNUS>

            % trigger player
            stop(comp.player);
        end

        %% Player Timer Calling

        function RefreshPlayer(comp, src, evt)
            src; %#ok<VUNUS>
            evt; %#ok<VUNUS>

            try
                % more than one frame, player continue
                frame_n = comp.Slider_Time.Limits(2);
                if frame_n > 1
                    if comp.frame_ptr == frame_n
                        % current frame is the last, just return
                        return;
                    else
                        % update frame pointer
                        comp.frame_ptr = comp.frame_ptr + 1;

                        % update current video frame and display
                        UpdateScreen(comp);

                        % update time slider (progress bar)
                        comp.Slider_Time.Value = comp.frame_ptr;
                    end
                end
            catch   % .
            end
        end

        function UpdateScreen(comp)
            % screen defination as followed:
            %% ======================== Screen ============================
            %  [TIME]                                        [Play Speed] %
            %                                                             %
            %                      [*]                                    %
            %                      Video Frame Image Data                 %
            %                                [>]                          %
            %              [<]                         [*]                %
            %                                                             %
            %  [Scale]                                        [Meta Data] %
            %% ============================================================

            % consumer: get a frame
            vf = comp.video.GetFrame(comp.frame_ptr);

            % display image
            comp.hImage.CData = vf.ImageData;

            % some real-time variables
            fr_cap = comp.kernel.Devices{"camera"}.AcquireFrameRate;    % Hz
            spr = 1/(comp.PLAYER_MIN_DURATION*comp.speed_ratio) / fr_cap;

            % update components
            updateComponentsOn(comp.UIAxes_Viewer, vf, comp.WithMarkers, ...
                comp.ScaleBarLength, spr, "larva");
        end

        %% Setup only once
        function UpdateScreenSize(comp)
            comp.screen_size = [comp.kernel.Devices{"camera"}.ROIWidth, ...
                                comp.kernel.Devices{"camera"}.ROIHeight];
            comp.UIAxes_Viewer.XLim = [0.5, comp.screen_size(1) + 0.5];
            comp.UIAxes_Viewer.YLim = [0.5, comp.screen_size(2) + 0.5];
            MaxVal = 2^comp.kernel.Devices{"camera"}.BitDepth - 1;
            comp.hImage = imshow(zeros(comp.screen_size([2,1])), ...
                "Parent",comp.UIAxes_Viewer, "DisplayRange", [0, MaxVal]);
            comp.UIAxes_Viewer.Title.String = "相机图像";
            comp.UIAxes_Viewer.FontSize = 14;
        end

        function UpdateAddonsLocation(comp)
            % scale bar
            comp.hScale.seglin.YData = [comp.screen_size(2) - EBScreenLayout.SCALE_OFFSET_Y.Value, ...
                                        comp.screen_size(2) - EBScreenLayout.SCALE_OFFSET_Y.Value];
            comp.hScale.txt.Position(2) = comp.screen_size(2) - EBScreenLayout.SCALE_OFFSET_Y.Value + 15;
            comp.hScale.txt.Position(3) = 1;

            % play speed
            comp.hSpeed.Position(1) = comp.screen_size(1) - EBScreenLayout.SPEED_OFFSET_X.Value;
            comp.hSpeed.Position(3) = 1;

            % meta data
            comp.hMeta.Position(1) = comp.screen_size(1) - EBScreenLayout.METADATA_OFFSET_X.Value;
            comp.hMeta.Position(2) = comp.screen_size(2) - EBScreenLayout.METADATA_OFFSET_Y.Value;
            comp.hMeta.Position(3) = 1;
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            % init variables
            comp.video = EBVideo.empty();
            comp.speed_ratio = 2;
            comp.frame_ptr = 0;
            comp.mpath = fileparts(mfilename("fullpath"));

            % init components
            [comp.hTime, comp.hScale, comp.hSpeed, comp.hMeta, comp.hBoxes, comp.hTrace] ...
                = initComponentsOn(comp.UIAxes_Viewer);

            % init timer slider
            comp.Slider_Time.Enable = "off";
            comp.Slider_Time.Limits = [0, 1];

            % init player timer
            % player: maintain frame pointer, update time slider value and screen 
            comp.player = timer("BusyMode",         "drop",...
                                "ExecutionMode",    "fixedRate", ...
                                "Name",             "EBVideoPlayer_Agent", ...
                                "Period",           comp.PLAYER_MIN_DURATION * comp.speed_ratio, ...
                                "StartDelay",       comp.PLAYER_MIN_DURATION,...
                                "TasksToExecute",   inf, ...
                                "TimerFcn",         @comp.RefreshPlayer);

            % init acquirer timer
            % acquirer: acquisition from kernel, update time slider limits
            comp.acquirer = timer("BusyMode",       "queue",...
                                  "ExecutionMode",  "fixedRate", ...
                                  "Name",           "EBVideoAcquirer_Agent", ...
                                  "Period",         comp.ACQUIRE_DURATION, ...
                                  "StartDelay",     comp.WAIT_KERNEL_DELAY,...
                                  "TasksToExecute", inf, ...
                                  "StartFcn",       @comp.StartAcquiring, ...
                                  "TimerFcn",       @comp.AcquirerTasks, ...
                                  "StopFcn",        @comp.StopAcquiring);

            % disable warnings
            warning("off", 'EBVideo:emptyVideo');
        end

        % Button pushed function: Button_Setting
        function Button_SettingPushed(comp, event)
            if ~isobject(comp.pConfig) || ~isvalid(comp.pConfig)
                comp.pConfig = PlayerConfiguration(comp);
                comp.pConfig.GetOptions(comp.withTimeStamp, ...
                                        comp.withScaleBar, ...
                                        comp.withPlaySpeed, ...
                                        comp.withMetadata, ...
                                        comp.withDetectBox, ...
                                        comp.withTraces);
            end
        end

        % Button pushed function: Button_SlowDown
        function Button_SlowDownPushed(comp, event)
            if comp.speed_ratio <= 4
                comp.speed_ratio = comp.speed_ratio * 2;

                % modify player period
                stop(comp.player);
                comp.player.Period = comp.PLAYER_MIN_DURATION * comp.speed_ratio;
                start(comp.player);
            else
                stop(comp.player);
                comp.Button_SlowDown.Enable = "off";
                start(comp.player);
            end
        end

        % Button pushed function: Button_Play
        function Button_PlayPushed(comp, event)
            % change player state
            if isequal("on", comp.player.Running)
                % stop and reset play butten
                stop(comp.player);
                comp.Button_Play.Icon = fullfile(comp.mpath, "sources", "play.png");
            else
                % reset play button and start
                comp.Button_Play.Icon = fullfile(comp.mpath, "sources", "pause.png");
                start(comp.player);
            end
        end

        % Button pushed function: Button_SpeedUp
        function Button_SpeedUpPushed(comp, event)
            if comp.speed_ratio >= 2
                comp.speed_ratio = comp.speed_ratio / 2;

                % modify player period
                stop(comp.player);
                comp.player.Period = comp.PLAYER_MIN_DURATION * comp.speed_ratio;
                start(comp.player);
            else
                stop(comp.player);
                comp.Button_SpeedUp.Enable = "off";
                start(comp.player);
            end
        end

        % Button pushed function: Button_CurrentFrame
        function Button_CurrentFramePushed(comp, event)
            % locate at last frame -1, avoid MATLAB bad real-time procedure
            comp.frame_ptr = max(2, comp.Slider_Time.Limits(2)-1);

            % update view immediately
            UpdateScreen(comp);

            % update time slider (progress bar)
            comp.Slider_Time.Value = comp.frame_ptr;

            % player follows acquirer
            if ~isequal("on", comp.player.Running) ...
                    && comp.Connect
                start(comp.player);
            end
        end

        % Callback function: Slider_Time, Slider_Time
        function Slider_TimeValueChanging(comp, event)
            % manual replay
            if isequal("on", comp.player.Running)
                stop(comp.player);

                % modify button
                comp.Button_Play.Icon = fullfile(comp.mpath, "sources", "play.png");
            end

            comp.frame_ptr = round(event.Value);

            % update video frame immediately
            comp.Slider_Time.Value = comp.frame_ptr;  % integer location
            UpdateScreen(comp);
        end

        % Button pushed function: Button_NextFrame
        function Button_NextFramePushed(comp, event)
            if comp.frame_ptr < comp.Slider_Time.Limits(2)
                % locate at last frame
                comp.frame_ptr = comp.frame_ptr + 1;

                % update view immediately
                UpdateScreen(comp);

                % update time slider (progress bar)
                comp.Slider_Time.Value = comp.frame_ptr;
            end
        end

        % Button pushed function: Button_PreviousFrame
        function Button_PreviousFramePushed(comp, event)
            if comp.frame_ptr > 1
                % locate at last frame
                comp.frame_ptr = comp.frame_ptr - 1;

                % update view immediately
                UpdateScreen(comp);

                % update time slider (progress bar)
                comp.Slider_Time.Value = comp.frame_ptr;
            end
        end
    end

    methods (Access = protected)
        
        % 公共属性值发生更改时执行的代码
        function update(comp)
            % Enable / Disable
            comp.Button_PreviousFrame.Enable =  comp.Enable;
            comp.Button_SlowDown.Enable = comp.Enable;
            comp.Button_Play.Enable = comp.Enable;
            comp.Button_SpeedUp.Enable = comp.Enable;
            comp.Button_NextFrame.Enable = comp.Enable;
            comp.Button_CurrentFrame.Enable = comp.Enable;

            if comp.Connect == true
                comp.Button_Play.Icon = fullfile(comp.mpath, "sources", "pause.png");
            else
                comp.Button_Play.Icon = fullfile(comp.mpath, "sources", "play.png");
            end

            % Switch timer
            if comp.Enable && comp.Connect
                if ~isequal(comp.acquirer.Running, "on")
                    start(comp.acquirer);
                end
            else
                stop(comp.acquirer);
            end
        end

        % 创建底层组件
        function setup(comp)

            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));

            comp.Position = [1 1 532 392];
            comp.BackgroundColor = [0.94 0.94 0.94];

            % Create GridLayout
            comp.GridLayout = uigridlayout(comp);
            comp.GridLayout.ColumnWidth = {'1x'};
            comp.GridLayout.RowHeight = {'1x', 40, 40};

            % Create UIAxes_Viewer
            comp.UIAxes_Viewer = uiaxes(comp.GridLayout);
            comp.UIAxes_Viewer.Toolbar.Visible = 'off';
            comp.UIAxes_Viewer.XTick = [];
            comp.UIAxes_Viewer.YTick = [];
            comp.UIAxes_Viewer.FontSize = 14;
            comp.UIAxes_Viewer.Layout.Row = 1;
            comp.UIAxes_Viewer.Layout.Column = 1;
            comp.UIAxes_Viewer.Visible = 'off';

            % Create GridLayout2
            comp.GridLayout2 = uigridlayout(comp.GridLayout);
            comp.GridLayout2.ColumnWidth = {'1x', 50, '1x'};
            comp.GridLayout2.RowHeight = {'1x'};
            comp.GridLayout2.Padding = [0 0 0 0];
            comp.GridLayout2.Layout.Row = 3;
            comp.GridLayout2.Layout.Column = 1;

            % Create GridLayout3
            comp.GridLayout3 = uigridlayout(comp.GridLayout2);
            comp.GridLayout3.ColumnWidth = {50, 50, '1x', 50};
            comp.GridLayout3.RowHeight = {'1x'};
            comp.GridLayout3.Padding = [0 0 0 0];
            comp.GridLayout3.Layout.Row = 1;
            comp.GridLayout3.Layout.Column = 3;

            % Create Button_SpeedUp
            comp.Button_SpeedUp = uibutton(comp.GridLayout3, 'push');
            comp.Button_SpeedUp.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Button_SpeedUpPushed, true);
            comp.Button_SpeedUp.Icon = fullfile(pathToMLAPP, 'sources', 'speed_up.png');
            comp.Button_SpeedUp.Layout.Row = 1;
            comp.Button_SpeedUp.Layout.Column = 1;
            comp.Button_SpeedUp.Text = '';

            % Create Button_CurrentFrame
            comp.Button_CurrentFrame = uibutton(comp.GridLayout3, 'push');
            comp.Button_CurrentFrame.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Button_CurrentFramePushed, true);
            comp.Button_CurrentFrame.Icon = fullfile(pathToMLAPP, 'sources', 'current.png');
            comp.Button_CurrentFrame.Layout.Row = 1;
            comp.Button_CurrentFrame.Layout.Column = 4;
            comp.Button_CurrentFrame.Text = '';

            % Create Button_NextFrame
            comp.Button_NextFrame = uibutton(comp.GridLayout3, 'push');
            comp.Button_NextFrame.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Button_NextFramePushed, true);
            comp.Button_NextFrame.Icon = fullfile(pathToMLAPP, 'sources', 'next.png');
            comp.Button_NextFrame.Layout.Row = 1;
            comp.Button_NextFrame.Layout.Column = 2;
            comp.Button_NextFrame.Text = '';

            % Create GridLayout4
            comp.GridLayout4 = uigridlayout(comp.GridLayout2);
            comp.GridLayout4.ColumnWidth = {50, '1x', 50, 50};
            comp.GridLayout4.RowHeight = {'1x'};
            comp.GridLayout4.Padding = [0 0 0 0];
            comp.GridLayout4.Layout.Row = 1;
            comp.GridLayout4.Layout.Column = 1;

            % Create Button_Setting
            comp.Button_Setting = uibutton(comp.GridLayout4, 'push');
            comp.Button_Setting.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Button_SettingPushed, true);
            comp.Button_Setting.Icon = fullfile(pathToMLAPP, 'sources', 'setting.png');
            comp.Button_Setting.Layout.Row = 1;
            comp.Button_Setting.Layout.Column = 1;
            comp.Button_Setting.Text = '';

            % Create Button_SlowDown
            comp.Button_SlowDown = uibutton(comp.GridLayout4, 'push');
            comp.Button_SlowDown.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Button_SlowDownPushed, true);
            comp.Button_SlowDown.Icon = fullfile(pathToMLAPP, 'sources', 'slow_down.png');
            comp.Button_SlowDown.Layout.Row = 1;
            comp.Button_SlowDown.Layout.Column = 4;
            comp.Button_SlowDown.Text = '';

            % Create Button_PreviousFrame
            comp.Button_PreviousFrame = uibutton(comp.GridLayout4, 'push');
            comp.Button_PreviousFrame.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Button_PreviousFramePushed, true);
            comp.Button_PreviousFrame.Icon = fullfile(pathToMLAPP, 'sources', 'prev.png');
            comp.Button_PreviousFrame.Layout.Row = 1;
            comp.Button_PreviousFrame.Layout.Column = 3;
            comp.Button_PreviousFrame.Text = '';

            % Create Button_Play
            comp.Button_Play = uibutton(comp.GridLayout2, 'push');
            comp.Button_Play.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Button_PlayPushed, true);
            comp.Button_Play.Icon = fullfile(pathToMLAPP, 'sources', 'play.png');
            comp.Button_Play.Layout.Row = 1;
            comp.Button_Play.Layout.Column = 2;
            comp.Button_Play.Text = '';

            % Create Slider_Time
            comp.Slider_Time = uislider(comp.GridLayout);
            comp.Slider_Time.Limits = [0 60];
            comp.Slider_Time.MajorTicks = [0 10 20 30 40 50 60];
            comp.Slider_Time.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @Slider_TimeValueChanging, true);
            comp.Slider_Time.ValueChangingFcn = matlab.apps.createCallbackFcn(comp, @Slider_TimeValueChanging, true);
            comp.Slider_Time.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60];
            comp.Slider_Time.Layout.Row = 2;
            comp.Slider_Time.Layout.Column = 1;
            
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end