classdef VideoPlayer < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout           matlab.ui.container.GridLayout
        GridLayout2          matlab.ui.container.GridLayout
        GridLayout4          matlab.ui.container.GridLayout
        Button_Setting       matlab.ui.control.Button
        Button_SlowDown      matlab.ui.control.Button
        Button_Play          matlab.ui.control.Button
        GridLayout3          matlab.ui.container.GridLayout
        Button_CurrentFrame  matlab.ui.control.Button
        Button_SpeedUp       matlab.ui.control.Button
        Slider               matlab.ui.control.Slider
        UIAxes               matlab.ui.control.UIAxes
    end

    methods (Access = protected)
        
        % 公共属性值发生更改时执行的代码
        function update(comp)
            % 使用此函数更新底层组件
            
        end

        % 创建底层组件
        function setup(comp)

            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));

            comp.Position = [1 1 480 480];
            comp.BackgroundColor = [0.94 0.94 0.94];

            % Create GridLayout
            comp.GridLayout = uigridlayout(comp);
            comp.GridLayout.ColumnWidth = {'1x'};
            comp.GridLayout.RowHeight = {'1x', 40, 40};

            % Create UIAxes
            comp.UIAxes = uiaxes(comp.GridLayout);
            title(comp.UIAxes, '相机图像(实时)')
            comp.UIAxes.Toolbar.Visible = 'off';
            comp.UIAxes.XTick = [];
            comp.UIAxes.YTick = [];
            comp.UIAxes.FontSize = 14;
            comp.UIAxes.Layout.Row = 1;
            comp.UIAxes.Layout.Column = 1;

            % Create Slider
            comp.Slider = uislider(comp.GridLayout);
            comp.Slider.Limits = [0 60];
            comp.Slider.MajorTicks = [0 10 20 30 40 50 60];
            comp.Slider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60];
            comp.Slider.Layout.Row = 2;
            comp.Slider.Layout.Column = 1;

            % Create GridLayout2
            comp.GridLayout2 = uigridlayout(comp.GridLayout);
            comp.GridLayout2.ColumnWidth = {'1x', 50, '1x'};
            comp.GridLayout2.RowHeight = {'1x'};
            comp.GridLayout2.Padding = [0 0 0 0];
            comp.GridLayout2.Layout.Row = 3;
            comp.GridLayout2.Layout.Column = 1;

            % Create GridLayout3
            comp.GridLayout3 = uigridlayout(comp.GridLayout2);
            comp.GridLayout3.ColumnWidth = {50, '1x', 50};
            comp.GridLayout3.RowHeight = {'1x'};
            comp.GridLayout3.Padding = [0 0 0 0];
            comp.GridLayout3.Layout.Row = 1;
            comp.GridLayout3.Layout.Column = 3;

            % Create Button_SpeedUp
            comp.Button_SpeedUp = uibutton(comp.GridLayout3, 'push');
            comp.Button_SpeedUp.Icon = fullfile(pathToMLAPP, 'sources', 'speed_up.png');
            comp.Button_SpeedUp.Layout.Row = 1;
            comp.Button_SpeedUp.Layout.Column = 1;
            comp.Button_SpeedUp.Text = '';

            % Create Button_CurrentFrame
            comp.Button_CurrentFrame = uibutton(comp.GridLayout3, 'push');
            comp.Button_CurrentFrame.Icon = fullfile(pathToMLAPP, 'sources', 'current.png');
            comp.Button_CurrentFrame.Layout.Row = 1;
            comp.Button_CurrentFrame.Layout.Column = 3;
            comp.Button_CurrentFrame.Text = '';

            % Create Button_Play
            comp.Button_Play = uibutton(comp.GridLayout2, 'push');
            comp.Button_Play.Icon = fullfile(pathToMLAPP, 'sources', 'play.png');
            comp.Button_Play.Layout.Row = 1;
            comp.Button_Play.Layout.Column = 2;
            comp.Button_Play.Text = '';

            % Create GridLayout4
            comp.GridLayout4 = uigridlayout(comp.GridLayout2);
            comp.GridLayout4.ColumnWidth = {50, '1x', 50};
            comp.GridLayout4.RowHeight = {'1x'};
            comp.GridLayout4.Padding = [0 0 0 0];
            comp.GridLayout4.Layout.Row = 1;
            comp.GridLayout4.Layout.Column = 1;

            % Create Button_SlowDown
            comp.Button_SlowDown = uibutton(comp.GridLayout4, 'push');
            comp.Button_SlowDown.Icon = fullfile(pathToMLAPP, 'sources', 'slow_down.png');
            comp.Button_SlowDown.Layout.Row = 1;
            comp.Button_SlowDown.Layout.Column = 3;
            comp.Button_SlowDown.Text = '';

            % Create Button_Setting
            comp.Button_Setting = uibutton(comp.GridLayout4, 'push');
            comp.Button_Setting.Icon = fullfile(pathToMLAPP, 'sources', 'setting.png');
            comp.Button_Setting.Layout.Row = 1;
            comp.Button_Setting.Layout.Column = 1;
            comp.Button_Setting.Text = '';
        end
    end
end