classdef ManualController < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ManualValvesControlUIFigure  matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        Panel                        matlab.ui.container.Panel
        GridLayout_ValvesGroup       matlab.ui.container.GridLayout
    end

    
    properties (Access = private)
        caller      (1,1)           % 1-by-1 HardwareConfiguration object handle
        valves      (:,1)   cell    % n-by-1 cell with Valves object
    end
    
    methods (Access = ?Valves)
        function write(app, port, command)
            % combine code from command

            code;

            % send code to 
            app.caller.SetValves(code);
        end
    end
    
    methods (Access = private)
        function InitData(app)
            % set
        end

        function UpdateApperance(app)

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.caller = caller;

            InitData(app);

            UpdateApperance(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ManualValvesControlUIFigure = uifigure('Visible', 'off');
            app.ManualValvesControlUIFigure.Name = 'ManualValvesControl';
            app.ManualValvesControlUIFigure.Icon = 'valves.png';
            app.GridLayout = uigridlayout(app.ManualValvesControlUIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.Panel.Title = '手动控制器(电磁阀)';
            app.GridLayout_ValvesGroup = uigridlayout(app.Panel);
            % Show the figure after all components are created
            app.ManualValvesControlUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ManualController(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ManualValvesControlUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ManualValvesControlUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ManualValvesControlUIFigure)
        end
    end
end