classdef Preference < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PreferenceUIFigure              matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        Panel_Main                      matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        EditField_Tooltip               matlab.ui.control.EditField
        GridLayout3                     matlab.ui.container.GridLayout
        Tree_Options                    matlab.ui.container.Tree
        Node_Appearance                 matlab.ui.container.TreeNode
        Node_Language                   matlab.ui.container.TreeNode
        Node_ProgressBar                matlab.ui.container.TreeNode
        Node_Style                      matlab.ui.container.TreeNode
        Node_DataStorage                matlab.ui.container.TreeNode
        Node_TemporaryFile              matlab.ui.container.TreeNode
        Node_ParallelComputing          matlab.ui.container.TreeNode
        Node_ConfigureCPU               matlab.ui.container.TreeNode
        Node_ConfigureGPU               matlab.ui.container.TreeNode
        Panel_Parameters                matlab.ui.container.Panel
        GridLayout_Parameters           matlab.ui.container.GridLayout
        DropDown_ProgressBarState       matlab.ui.control.DropDown
        Label_7                         matlab.ui.control.Label
        Spinner_NWorkersGPU             matlab.ui.control.Spinner
        GPUSpinnerLabel                 matlab.ui.control.Label
        Spinner_NWorkersCPU             matlab.ui.control.Spinner
        Label_6                         matlab.ui.control.Label
        DropDown_CameraCache            matlab.ui.control.DropDown
        Label_5                         matlab.ui.control.Label
        DropDown_WindowStyle            matlab.ui.control.DropDown
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        GridLayout7                     matlab.ui.container.GridLayout
        DropDown_ProgressBarBackColor   matlab.ui.control.DropDown
        EditField_ProgressBarBackColor  matlab.ui.control.EditField
        Label_2                         matlab.ui.control.Label
        GridLayout6                     matlab.ui.container.GridLayout
        DropDown_ProgressBarForeColor   matlab.ui.control.DropDown
        EditField_ProgressBarForeColor  matlab.ui.control.EditField
        DropDown_Language               matlab.ui.control.DropDown
        Label                           matlab.ui.control.Label
    end

    
    properties (Access = private)
        caller

        opts
    end
    
    methods (Access = public)
        
        function CloseThis(app)
            % ~
            delete(app);
        end
    end

    methods (Access = private)

        function InitData(app)
            app.opts = app.caller.GetPreferOptions();
        end

        function InitAppearance(app)
            %% Init Appearence Profile
            app.DropDown_Language.Value = app.opts.Language;

            app.DropDown_ProgressBarState.Value = app.opts.ProgressBarVisible;

            app.EditField_ProgressBarForeColor.Enable = app.opts.ProgressBarVisible;
            app.EditField_ProgressBarForeColor.Value = ...
                string(app.opts.ProgressBarForeColor).join(",");
            app.DropDown_ProgressBarForeColor.Enable = app.opts.ProgressBarVisible;
            app.DropDown_ProgressBarForeColor.BackgroundColor = app.opts.ProgressBarForeColor;

            app.EditField_ProgressBarBackColor.Enable = app.opts.ProgressBarVisible;
            app.EditField_ProgressBarBackColor.Value = ...
                string(app.opts.ProgressBarBackColor).join(",");
            app.DropDown_ProgressBarBackColor.Enable = app.opts.ProgressBarVisible;
            app.DropDown_ProgressBarBackColor.BackgroundColor = app.opts.ProgressBarBackColor;

            app.DropDown_WindowStyle.Value = app.opts.WindowStyle;
            
            %% Init Data Storage Profile
            app.DropDown_CameraCache.Value = app.opts.CameraCache;

            %% Init Parallel Computing Profile
            app.Spinner_NWorkersCPU.Enable = app.opts.IsParallelValid;
            app.Spinner_NWorkersCPU.Limits = [double(app.opts.CoresEnableCPU>1), ...
                                              app.opts.CoresEnableCPU];
            app.Spinner_NWorkersCPU.Value = app.opts.CoresEnableCPU;

            app.Spinner_NWorkersGPU.Enable = app.opts.IsGPUValid;
            app.Spinner_NWorkersGPU.Limits = [0, max(1, app.opts.CoresEnableGPU)];
            app.Spinner_NWorkersGPU.Value = app.opts.CoresEnableGPU;

            %% Init Display Profile
            % hidden all
            HiddenAllParams(app);
        end

        function HiddenAllParams(app)
            app.GridLayout_Parameters.RowHeight = [0,0,0,0,0,0,0,0];
        end

        function DisplayParamsWith(app, Tag)
            switch Tag
                case "APPEARANCE"
                    app.GridLayout_Parameters.RowHeight = [23,23,23,23,23,0,0,0];
                case "DATA_STORAGE"
                    app.GridLayout_Parameters.RowHeight = [0,0,0,0,0,23,0,0];
                case "PARALLEL_COMPUTING"
                    app.GridLayout_Parameters.RowHeight = [0,0,0,0,0,0,23,23];
                case "LANGUAGE"
                    app.GridLayout_Parameters.RowHeight = [23,0,0,0,0,0,0,0];
                case "PROGRESS_BAR"
                    app.GridLayout_Parameters.RowHeight = [0,23,23,23,0,0,0,0];
                case "STYLE"
                    app.GridLayout_Parameters.RowHeight = [0,0,0,0,23,0,0,0];
                case "TEMPORARY_FILE"
                    app.GridLayout_Parameters.RowHeight = [0,0,0,0,0,23,0,0];
                case "CONFIGURATION_CPU"
                    app.GridLayout_Parameters.RowHeight = [0,0,0,0,0,0,23,0];
                case "CONFIGURATION_GPU"
                    app.GridLayout_Parameters.RowHeight = [0,0,0,0,0,0,23,0];
                otherwise
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.caller = mainapp;

            InitData(app);

            InitAppearance(app);
        end

        % Close request function: PreferenceUIFigure
        function PreferenceUIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Selection changed function: Tree_Options
        function Tree_OptionsSelectionChanged(app, event)
            selectedNodes = app.Tree_Options.SelectedNodes;
            
            DisplayParamsWith(app, selectedNodes.Tag);
        end

        % Value changed function: DropDown_Language
        function DropDown_LanguageValueChanged(app, event)
            value = app.DropDown_Language.Value;
            
            app.opts.Language = value;
        end

        % Value changed function: DropDown_ProgressBarState
        function DropDown_ProgressBarStateValueChanged(app, event)
            value = app.DropDown_ProgressBarState.Value;
            
            app.opts.ProgressBarVisible = value;

            app.caller.SetPreferOptions(app.opts);
        end

        % Value changed function: EditField_ProgressBarForeColor
        function EditField_ProgressBarForeColorValueChanged(app, event)
            value = app.EditField_ProgressBarForeColor.Value;
            
            value = str2num(value); %#ok<ST2NM>
            if ~isempty(value) && numel(value)==3 && all(value>=0 & value<=1)
                app.opts.ProgressBarForeColor = value;
                app.DropDown_ProgressBarForeColor.BackgroundColor = value;

                app.caller.SetPreferOptions(app.opts);
            else
                app.EditField_ProgressBarForeColor.Value = ...
                    string(app.opts.ProgressBarForeColor).join(",");
            end
        end

        % Value changed function: EditField_ProgressBarBackColor
        function EditField_ProgressBarBackColorValueChanged(app, event)
            value = app.EditField_ProgressBarBackColor.Value;

            value = str2num(value); %#ok<ST2NM>
            if ~isempty(value) && numel(value)==3 && all(value>=0 & value<=1)
                app.opts.ProgressBarBackColor = value;
                app.DropDown_ProgressBarBackColor.BackgroundColor = value;

                app.caller.SetPreferOptions(app.opts);
            else
                app.EditField_ProgressBarBackColor.Value = ...
                    string(app.opts.ProgressBarBackColor).join(",");
            end
        end

        % Value changed function: DropDown_WindowStyle
        function DropDown_WindowStyleValueChanged(app, event)
            value = app.DropDown_WindowStyle.Value;
            
            app.opts.WindowStyle = value;

            app.caller.SetPreferOptions(app.opts);
        end

        % Value changed function: DropDown_CameraCache
        function DropDown_CameraCacheValueChanged(app, event)
            value = app.DropDown_CameraCache.Value;
            
            app.opts.CameraCache = value;

            app.caller.SetPreferOptions(app.opts);
        end

        % Value changed function: Spinner_NWorkersCPU
        function Spinner_NWorkersCPUValueChanged(app, event)
            value = app.Spinner_NWorkersCPU.Value;

            app.opts.CoresEnableCPU = value;

            app.caller.SetPreferOptions(app.opts);
        end

        % Value changed function: Spinner_NWorkersGPU
        function Spinner_NWorkersGPUValueChanged(app, event)
            value = app.Spinner_NWorkersGPU.Value;
            
            app.opts.CoresEnableGPU = value;

            app.caller.SetPreferOptions(app.opts);
        end

        % Clicked callback: DropDown_ProgressBarBackColor
        function DropDown_ProgressBarBackColorClicked(app, event)
            value = uisetcolor(app.opts.ProgressBarBackColor, "选择背景颜色");
            value = round(value, 2);

            app.opts.ProgressBarBackColor = value;
            app.DropDown_ProgressBarBackColor.BackgroundColor = value;
            app.EditField_ProgressBarBackColor.Value = string(value).join(",");

            app.caller.SetPreferOptions(app.opts);
        end

        % Clicked callback: DropDown_ProgressBarForeColor
        function DropDown_ProgressBarForeColorClicked(app, event)
            value = uisetcolor(app.opts.ProgressBarForeColor, "选择前景颜色");
            value = round(value, 2);

            app.opts.ProgressBarForeColor = value;
            app.DropDown_ProgressBarForeColor.BackgroundColor = value;
            app.EditField_ProgressBarForeColor.Value = string(value).join(",");

            app.caller.SetPreferOptions(app.opts);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.PreferenceUIFigure = uifigure('Visible', 'off');
            app.PreferenceUIFigure.Name = 'Preference';
            app.PreferenceUIFigure.Icon = fullfile(pathToMLAPP, 'sources', 'controls.png');
            app.PreferenceUIFigure.CloseRequestFcn = createCallbackFcn(app, @PreferenceUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.PreferenceUIFigure);
            app.Panel_Main = uipanel(app.GridLayout);
            app.Panel_Main.Title = '首选项';
            app.GridLayout4 = uigridlayout(app.Panel_Main);
            app.GridLayout3 = uigridlayout(app.GridLayout4);
            app.Panel_Parameters = uipanel(app.GridLayout3);
            app.Panel_Parameters.Title = '参数';
            app.GridLayout_Parameters = uigridlayout(app.Panel_Parameters);
            app.Label = uilabel(app.GridLayout_Parameters);
            app.Label.Text = '语言';
            app.DropDown_Language = uidropdown(app.GridLayout_Parameters);
            app.DropDown_Language.Items = {'简体中文', '繁體中文', 'English'};
            app.DropDown_Language.ItemsData = {'Chinese', 'Traditional', 'English'};
            app.DropDown_Language.ValueChangedFcn = createCallbackFcn(app, @DropDown_LanguageValueChanged, true);
            app.DropDown_Language.Value = 'Chinese';
            app.GridLayout6 = uigridlayout(app.GridLayout_Parameters);
            app.EditField_ProgressBarForeColor = uieditfield(app.GridLayout6, 'text');
            app.EditField_ProgressBarForeColor.ValueChangedFcn = createCallbackFcn(app, @EditField_ProgressBarForeColorValueChanged, true);
            app.EditField_ProgressBarForeColor.Value = '0.30,0.75,0.93';
            app.DropDown_ProgressBarForeColor = uidropdown(app.GridLayout6);
            app.DropDown_ProgressBarForeColor.Items = {};
            app.DropDown_ProgressBarForeColor.ClickedFcn = createCallbackFcn(app, @DropDown_ProgressBarForeColorClicked, true);
            app.DropDown_ProgressBarForeColor.Value = {};
            app.Label_2 = uilabel(app.GridLayout_Parameters);
            app.Label_2.Text = '前景颜色';
            app.GridLayout7 = uigridlayout(app.GridLayout_Parameters);
            app.EditField_ProgressBarBackColor = uieditfield(app.GridLayout7, 'text');
            app.EditField_ProgressBarBackColor.ValueChangedFcn = createCallbackFcn(app, @EditField_ProgressBarBackColorValueChanged, true);
            app.EditField_ProgressBarBackColor.Value = '0.90,0.90,0.90';
            app.DropDown_ProgressBarBackColor = uidropdown(app.GridLayout7);
            app.DropDown_ProgressBarBackColor.Items = {};
            app.DropDown_ProgressBarBackColor.ClickedFcn = createCallbackFcn(app, @DropDown_ProgressBarBackColorClicked, true);
            app.DropDown_ProgressBarBackColor.Value = {};
            app.Label_3 = uilabel(app.GridLayout_Parameters);
            app.Label_3.Text = '背景颜色';
            app.Label_4 = uilabel(app.GridLayout_Parameters);
            app.Label_4.Text = '窗体风格';
            app.DropDown_WindowStyle = uidropdown(app.GridLayout_Parameters);
            app.DropDown_WindowStyle.Items = {'简洁', '经典'};
            app.DropDown_WindowStyle.ItemsData = {'simple', 'classical'};
            app.DropDown_WindowStyle.ValueChangedFcn = createCallbackFcn(app, @DropDown_WindowStyleValueChanged, true);
            app.DropDown_WindowStyle.Value = 'classical';
            app.Label_5 = uilabel(app.GridLayout_Parameters);
            app.Label_5.Text = '相机缓冲区';
            app.DropDown_CameraCache = uidropdown(app.GridLayout_Parameters);
            app.DropDown_CameraCache.Items = {'内存', '硬盘'};
            app.DropDown_CameraCache.ItemsData = {'memory', 'hard drive'};
            app.DropDown_CameraCache.ValueChangedFcn = createCallbackFcn(app, @DropDown_CameraCacheValueChanged, true);
            app.DropDown_CameraCache.Value = 'memory';
            app.Label_6 = uilabel(app.GridLayout_Parameters);
            app.Label_6.Text = 'CPU核心数';
            app.Spinner_NWorkersCPU = uispinner(app.GridLayout_Parameters);
            app.Spinner_NWorkersCPU.Limits = [1 8];
            app.Spinner_NWorkersCPU.RoundFractionalValues = 'on';
            app.Spinner_NWorkersCPU.ValueDisplayFormat = '%.0f';
            app.Spinner_NWorkersCPU.ValueChangedFcn = createCallbackFcn(app, @Spinner_NWorkersCPUValueChanged, true);
            app.Spinner_NWorkersCPU.Value = 8;
            app.GPUSpinnerLabel = uilabel(app.GridLayout_Parameters);
            app.GPUSpinnerLabel.Text = 'GPU核心数';
            app.Spinner_NWorkersGPU = uispinner(app.GridLayout_Parameters);
            app.Spinner_NWorkersGPU.Limits = [0 2];
            app.Spinner_NWorkersGPU.ValueDisplayFormat = '%.0f';
            app.Spinner_NWorkersGPU.ValueChangedFcn = createCallbackFcn(app, @Spinner_NWorkersGPUValueChanged, true);
            app.Label_7 = uilabel(app.GridLayout_Parameters);
            app.Label_7.Text = '进度条状态';
            app.DropDown_ProgressBarState = uidropdown(app.GridLayout_Parameters);
            app.DropDown_ProgressBarState.Items = {'启用', '禁用'};
            app.DropDown_ProgressBarState.ItemsData = {'on', 'off'};
            app.DropDown_ProgressBarState.ValueChangedFcn = createCallbackFcn(app, @DropDown_ProgressBarStateValueChanged, true);
            app.DropDown_ProgressBarState.Value = 'on';
            app.Tree_Options = uitree(app.GridLayout3);
            app.Tree_Options.SelectionChangedFcn = createCallbackFcn(app, @Tree_OptionsSelectionChanged, true);
            app.Node_Appearance = uitreenode(app.Tree_Options);
            app.Node_Appearance.Tag = 'APPEARANCE';
            app.Node_Appearance.Text = '外观';
            app.Node_Language = uitreenode(app.Node_Appearance);
            app.Node_Language.Tag = 'LANGUAGE';
            app.Node_Language.Text = '语言';
            app.Node_ProgressBar = uitreenode(app.Node_Appearance);
            app.Node_ProgressBar.Tag = 'PROGRESS_BAR';
            app.Node_ProgressBar.Text = '进度条';
            app.Node_Style = uitreenode(app.Node_Appearance);
            app.Node_Style.Tag = 'STYLE';
            app.Node_Style.Text = '风格';
            app.Node_DataStorage = uitreenode(app.Tree_Options);
            app.Node_DataStorage.Tag = 'DATA_STORAGE';
            app.Node_DataStorage.Text = '数据管理';
            app.Node_TemporaryFile = uitreenode(app.Node_DataStorage);
            app.Node_TemporaryFile.Tag = 'TEMPORARY_FILE';
            app.Node_TemporaryFile.Text = '临时文件';
            app.Node_ParallelComputing = uitreenode(app.Tree_Options);
            app.Node_ParallelComputing.Tag = 'PARALLEL_COMPUTING';
            app.Node_ParallelComputing.Text = '并行计算';
            app.Node_ConfigureCPU = uitreenode(app.Node_ParallelComputing);
            app.Node_ConfigureCPU.Tag = 'CONFIGURATION_CPU';
            app.Node_ConfigureCPU.Text = '配置CPU';
            app.Node_ConfigureGPU = uitreenode(app.Node_ParallelComputing);
            app.Node_ConfigureGPU.Tag = 'CONFIGURATION_GPU';
            app.Node_ConfigureGPU.Text = '配置GPU';
            app.EditField_Tooltip = uieditfield(app.GridLayout4, 'text');
            app.EditField_Tooltip.Editable = 'off';
            app.EditField_Tooltip.Tooltip = {'提示'};
            % Show the figure after all components are created
            app.PreferenceUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Preference(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.PreferenceUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.PreferenceUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PreferenceUIFigure)
        end
    end
end